classdef pokeri_kello < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        playerButton_2               matlab.ui.control.Button
        playerButton                 matlab.ui.control.Button
        playersEditField             matlab.ui.control.NumericEditField
        playersEditFieldLabel        matlab.ui.control.Label
        LevelupinLabel               matlab.ui.control.Label
        secondsEditField             matlab.ui.control.NumericEditField
        secondsEditFieldLabel        matlab.ui.control.Label
        minutesEditField             matlab.ui.control.NumericEditField
        minutesEditFieldLabel        matlab.ui.control.Label
        BigBlindGauge                matlab.ui.control.LinearGauge
        BigBlindGaugeLabel           matlab.ui.control.Label
        AveragestackEditField        matlab.ui.control.EditField
        AveragestackEditFieldLabel   matlab.ui.control.Label
        LeveltimeEditField           matlab.ui.control.NumericEditField
        LeveltimeEditFieldLabel      matlab.ui.control.Label
        BBEditField                  matlab.ui.control.NumericEditField
        BBEditFieldLabel             matlab.ui.control.Label
        StartingstackEditField       matlab.ui.control.NumericEditField
        StartingstackEditFieldLabel  matlab.ui.control.Label
        Switch                       matlab.ui.control.ToggleSwitch
        SwitchLabel                  matlab.ui.control.Label
        SetvaluesButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        
        StartingStack = 5000;
        BB = 100;
        Level_time = 20;
        clock_set = 0;
        i = 1;
        MoneyInPlay = 0;
        players;
        level = 1;
        
        minutes = 0;
        seconds = 59;
        shit = 0;     %to make sure the 'while app.Switch.Value == "Off"' modifies the valus only once 


        % Description
    end
    
    methods (Access = private)
        
        function blindLevels = updateBBgauge(app)
            BBlabels = {};
            BBvalues = ones(1,10);
            for iter = 1:10
                BBvalues(iter) = app.BBEditField.Value * iter;  
                coefficient = 1.5;
                if iter == 1
                    BBlabels{iter} = num2str(app.BBEditField.Value);
                else
                    BBlabels{iter} = num2str(...
                        round((str2num(BBlabels{iter-1}) * coefficient)/app.BBEditField.Value)...
                        * app.BBEditField.Value);
                end
                
            end
            app.BigBlindGauge.Limits = [0 BBvalues(end)];
            app.BigBlindGauge.MajorTickLabels = BBlabels;
            app.BigBlindGauge.MajorTicks = BBvalues;
            app.BigBlindGauge.Value = app.BigBlindGauge.MajorTicks(app.level);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            while app.Switch.Value == "On"
                for x = app.minutes:-1:0
                    app.minutesEditField.Value = x;
                    for y = app.seconds:-1:0
                        while app.Switch.Value == "Off"
                            if app.shit ==0
                                app.minutes = x;       %to keep values when break
                                app.seconds = y;
                            end
                            pause(1);
                            app.shit = app.shit+1;
                        end
                        app.shit =0;
                        app.secondsEditField.Value = y;
                        pause(1)
                    end

                    if y == 0
                        app.seconds = 59;
                        if x == 0

                            sound(1);
                            app.BigBlindGauge.Value = app.BigBlindGauge.Value + 50;                           
                            app.minutes = app.LeveltimeEditField.Value-1;
                        end
                    end
                end
            end      
        end

        % Value changed function: BBEditField
        function BBEditFieldValueChanged(app, event)
            app.BB = app.BBEditField.Value;
            
        end

        % Button pushed function: SetvaluesButton
        function SetvaluesButtonPushed(app, event)
           app.BigBlindGauge.Value = app.BBEditField.Value;

           app.seconds = 59;
           app.Level_time = app.LeveltimeEditField.Value;
           
           app.players = app.playersEditField.Value;
           app.level = 1;
           
           app.minutes = app.LeveltimeEditField.Value-1;
           app.minutesEditField.Value = app.LeveltimeEditField.Value-1;
           app.secondsEditField.Value = app.seconds;
           app.MoneyInPlay = app.StartingstackEditField.Value * app.players;
           app.AveragestackEditField.Value = num2str(app.MoneyInPlay / app.players);
           
           updateBBgauge(app)
           SwitchValueChanged(app, event)

        end

        % Value changed function: playersEditField
        function playersEditFieldValueChanged(app, event)
            %app.AveragestackEditField.Value = app.MoneyInPlay.Value/app.playersEditField.Value;
            %does not work
        end

        % Button pushed function: playerButton
        function playerButtonPushed(app, event)
            app.players = app.players + 1;
            app.playersEditField.Value = app.players;
            app.MoneyInPlay = app.MoneyInPlay + app.StartingstackEditField.Value;
            app.AveragestackEditField.Value = num2str(app.MoneyInPlay / app.players);
        end

        % Button pushed function: playerButton_2
        function playerButton_2Pushed(app, event)
            app.players = app.players - 1;
            app.playersEditField.Value = app.players;
            app.AveragestackEditField.Value = num2str(app.MoneyInPlay / app.players);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SetvaluesButton = uibutton(app.UIFigure, 'push');
            app.SetvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @SetvaluesButtonPushed, true);
            app.SetvaluesButton.Text = 'Set values';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.StartingstackEditFieldLabel = uilabel(app.UIFigure);
            app.StartingstackEditFieldLabel.Text = 'Starting stack';
            app.StartingstackEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingstackEditField.Limits = [0 Inf];
            app.StartingstackEditField.Value = 5000;
            app.BBEditFieldLabel = uilabel(app.UIFigure);
            app.BBEditFieldLabel.Text = {'BB'; ''};
            app.BBEditField = uieditfield(app.UIFigure, 'numeric');
            app.BBEditField.Limits = [0 Inf];
            app.BBEditField.ValueChangedFcn = createCallbackFcn(app, @BBEditFieldValueChanged, true);
            app.BBEditField.Value = 50;
            app.LeveltimeEditFieldLabel = uilabel(app.UIFigure);
            app.LeveltimeEditFieldLabel.Text = {'Level time'; ''};
            app.LeveltimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeveltimeEditField.Limits = [1 Inf];
            app.LeveltimeEditField.Value = 20;
            app.AveragestackEditFieldLabel = uilabel(app.UIFigure);
            app.AveragestackEditFieldLabel.Text = 'Average stack';
            app.AveragestackEditField = uieditfield(app.UIFigure, 'text');
            app.BigBlindGaugeLabel = uilabel(app.UIFigure);
            app.BigBlindGaugeLabel.Text = 'Big Blind';
            app.BigBlindGauge = uigauge(app.UIFigure, 'linear');
            app.BigBlindGauge.Limits = [0 500];
            app.BigBlindGauge.MajorTicks = [0 50 100 150 200 250 300 350 400 450 500 550 600];
            app.BigBlindGauge.MajorTickLabels = {'0', '50', '100', '150', '200', '300', '400', '500', '600', '800', '1000', '1200', '1600'};
            app.BigBlindGauge.MinorTicks = [];
            app.minutesEditFieldLabel = uilabel(app.UIFigure);
            app.minutesEditFieldLabel.Text = 'minutes';
            app.minutesEditField = uieditfield(app.UIFigure, 'numeric');
            app.secondsEditFieldLabel = uilabel(app.UIFigure);
            app.secondsEditFieldLabel.Text = 'seconds';
            app.secondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.LevelupinLabel = uilabel(app.UIFigure);
            app.LevelupinLabel.Text = 'Level up in:';
            app.playersEditFieldLabel = uilabel(app.UIFigure);
            app.playersEditFieldLabel.Text = 'players';
            app.playersEditField = uieditfield(app.UIFigure, 'numeric');
            app.playersEditField.Limits = [0 Inf];
            app.playersEditField.ValueChangedFcn = createCallbackFcn(app, @playersEditFieldValueChanged, true);
            app.playersEditField.Value = 3;
            app.playerButton = uibutton(app.UIFigure, 'push');
            app.playerButton.ButtonPushedFcn = createCallbackFcn(app, @playerButtonPushed, true);
            app.playerButton.Text = '+1 player';
            app.playerButton_2 = uibutton(app.UIFigure, 'push');
            app.playerButton_2.ButtonPushedFcn = createCallbackFcn(app, @playerButton_2Pushed, true);
            app.playerButton_2.Text = '-1 player';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pokeri_kello

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end